classdef Lab0201 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        UIAxes                       matlab.ui.control.UIAxes
        Below60HappyAbove60SadLabel  matlab.ui.control.Label
        GetImageButton               matlab.ui.control.StateButton
        Result                       matlab.ui.control.EditField
        Get_Number                   matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Value changed function: GetImageButton
        function GetImageButtonValueChanged(app, event)
            %value = app.GetImageButton.Value;
            
            if app.Get_Number.Value <= 60
                smile = imread('smile.jpg');
                imshow(smile, 'parent', app.UIAxes);
                app.Result.Value = 'Happy';
            else 
                sad = imread('sad.jpg');
                imshow(sad, 'parent', app.UIAxes);
                app.Result.Value = 'Sad';
            end
            
        end

        % Value changed function: Result
        function ResultValueChanged(app, event)
            value = app.Result.Value;
            
        end

        % Value changing function: Result
        function ResultValueChanging(app, event)
            changingValue = event.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Below60HappyAbove60SadLabel = uilabel(app.UIFigure);
            app.Below60HappyAbove60SadLabel.Text = {'Below  60 --> Happy :)'; 'Above 60 --> Sad:('};
            app.GetImageButton = uibutton(app.UIFigure, 'state');
            app.GetImageButton.ValueChangedFcn = createCallbackFcn(app, @GetImageButtonValueChanged, true);
            app.GetImageButton.Text = 'Get Image ';
            app.Result = uieditfield(app.UIFigure, 'text');
            app.Result.ValueChangedFcn = createCallbackFcn(app, @ResultValueChanged, true);
            app.Result.ValueChangingFcn = createCallbackFcn(app, @ResultValueChanging, true);
            app.Result.Editable = 'off';
            app.Get_Number = uieditfield(app.UIFigure, 'numeric');
            app.Get_Number.ValueDisplayFormat = '%.0f';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Lab0201

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end