classdef Lab0202 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        SimpleCalculatorLabel      matlab.ui.control.Label
        Error_Con                  matlab.ui.control.EditField
        Error_Type                 matlab.ui.control.EditField
        First_Operand              matlab.ui.control.NumericEditField
        Second_Operand             matlab.ui.control.NumericEditField
        Operator                   matlab.ui.control.EditField
        Equal                      matlab.ui.control.EditField
        ArithmaticOperationsLabel  matlab.ui.control.Label
        ListBox                    matlab.ui.control.ListBox
        EqualButton                matlab.ui.control.StateButton
        YAxisLabel                 matlab.ui.control.Label
        XAxisLabel                 matlab.ui.control.Label
        Operator_2                 matlab.ui.control.EditField
        Y_Axis_Field               matlab.ui.control.EditField
        X_Axis_Field               matlab.ui.control.EditField
        ResultLabel                matlab.ui.control.Label
        Result_2                   matlab.ui.control.EditField
        Equal_2                    matlab.ui.control.EditField
        ListBox_2                  matlab.ui.control.ListBox
        EqualButton_2              matlab.ui.control.StateButton
        Error_Con_2                matlab.ui.control.EditField
        Error_Type_2               matlab.ui.control.EditField
        EnterequationyxLabel       matlab.ui.control.Label
        Equation                   matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
        PlottingButtonsGroupButtonGroup  matlab.ui.container.ButtonGroup
        XAxisButton                matlab.ui.control.RadioButton
        YAxisButton                matlab.ui.control.RadioButton
        XYAxisButton               matlab.ui.control.RadioButton
        ResultButton               matlab.ui.control.RadioButton
        SineButton                 matlab.ui.control.RadioButton
        CosineButton               matlab.ui.control.RadioButton
        EquationButton             matlab.ui.control.RadioButton
        XLabel                     matlab.ui.control.Label
        XStart                     matlab.ui.control.NumericEditField
        TOLabel                    matlab.ui.control.Label
        XEnd                       matlab.ui.control.NumericEditField
        ShowButton                 matlab.ui.control.StateButton
        HideButton                 matlab.ui.control.StateButton
        Result                     matlab.ui.control.EditField
    end

    methods (Access = private)

        % Value changed function: EqualButton
        function EqualButtonValueChanged(app, event)
            switch app.ListBox.Value
                case '+'
                    app.Error_Type.Value = 'No error';
                    app.Result.Value = string(app.First_Operand.Value + app.Second_Operand.Value);
                case '-'
                    app.Error_Type.Value = 'No error';
                    app.Result.Value = string(app.First_Operand.Value - app.Second_Operand.Value);
                case '*'
                    app.Error_Type.Value = 'No error';
                    app.Result.Value = string(app.First_Operand.Value * app.Second_Operand.Value);
                case '/'
                    if app.Second_Operand.Value == 0
                        app.Error_Type.Value = 'Can not divide by zero';
                        app.Result.Value = '';
                    else
                        app.Error_Type.Value = 'No error';
                        app.Result.Value = string(app.First_Operand.Value / app.Second_Operand.Value);
                    end
                case '%'
                    if app.Second_Operand.Value == 0
                        app.Error_Type.Value = 'Can not divide by zero';
                        app.Result.Value = '';
                    else
                        app.Error_Type.Value = 'No error';
                        app.Result.Value = string(mod(app.First_Operand.Value, app.Second_Operand.Value));
                    end
                    
            end
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            app.Operator.Value = app.ListBox.Value;
            
        end

        % Value changed function: EqualButton_2
        function EqualButton_2ValueChanged(app, event)
            
            str1 = app.Y_Axis_Field.Value;
            if isempty(str1)
               Vec1_len = 0;
            else 
                str1 = str1(2: end - 1);
                str1 = strip(str1);
                Vec1 = str2double(strsplit(str1));
                Vec1_len = length(Vec1);
            end
            
            str1 = app.X_Axis_Field.Value;
            if isempty(str1)
                Vec2_len = 0;
            else
                str1 = str1(2: end - 1);
                str1 = strip(str1);
                Vec2 = str2double(strsplit(str1));
                Vec2_len = length(Vec2);
            end
            
            if Vec1_len == Vec2_len && Vec1_len ~= 0
                
                if sum(isnan(Vec1)) ~= 0
                        app.Error_Type_2.Value = 'Error Y has non double values';
                elseif sum(isnan(Vec2)) ~= 0
                    app.Error_Type_2.Value = 'Error X has non double values';
                else     
                    app.Error_Type_2.Value = 'No Error';
                        
                    switch app.ListBox_2.Value
                        case '+'
                            app.Error_Type_2.Value = 'No error';
                            app.Operator_2.Value = '+';
                            app.Result_2.Value = mat2str(Vec1 + Vec2);
                            
                        case '-'
                            app.Error_Type_2.Value = 'No error';
                            app.Operator_2.Value = '-';
                            app.Result_2.Value = mat2str(Vec1 - Vec2);
                            
                        case '*'
                            app.Error_Type_2.Value = 'No error';
                            app.Operator_2.Value = '*';
                            app.Result_2.Value = mat2str(Vec1 .* Vec2);
                            
                        case '/'
                            if any(Vec2(:) == 0)
                                app.Error_Type_2.Value = 'Can not divide by zero';
                                app.Result_2.Value = '';
                            else
                                app.Error_Type_2.Value = 'No error';
                                app.Operator_2.Value = '/';
                                app.Result_2.Value = mat2str(Vec1 ./ Vec2);
                            end
                        case '%'
                            if any(Vec2(:) == 0)
                                app.Error_Type_2.Value = 'Can not divide by zero';
                                app.Result_2.Value = '';
                            else
                                app.Error_Type_2.Value = 'No error';
                                app.Operator_2.Value = '%';
                                app.Result_2.Value = mat2str(mod(Vec1, Vec2));
                            end
                            
                    end
                end
            else
                app.Result_2.Value = '';
                app.Error_Type_2.Value = 'Error Not Equal length';
            end
            
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            app.Operator_2.Value = app.ListBox_2.Value;
            
        end

        % Selection changed function: PlottingButtonsGroupButtonGroup
        function PlottingButtonsGroupButtonGroupSelectionChanged(app, event)
            
        end

        % Value changed function: ShowButton
        function ShowButtonValueChanged(app, event)
            selectedButton = app.PlottingButtonsGroupButtonGroup.SelectedObject;
            
            switch selectedButton
                case app.XAxisButton
                    str1 = app.X_Axis_Field.Value;
                    str1 = str1(2: end - 1);
                    str1 = strip(str1);
                    X = str2double(strsplit(str1));
                    if sum(isnan(X)) ~= 0
                        cla(app.UIAxes);
                        app.Error_Type_2.Value = 'Error X has non double values';
                    else
                        app.Error_Type_2.Value = 'No Error';
                        plot(app.UIAxes, X, '*');
                    end
                    
                case app.YAxisButton
                    str1 = app.Y_Axis_Field.Value;
                    str1 = str1(2: end - 1);
                    str1 = strip(str1);
                    Y = str2double(strsplit(str1));
                    if sum(isnan(Y)) ~= 0
                        cla(app.UIAxes);
                        app.Error_Type_2.Value = 'Error Y has non double values';
                    else
                        app.Error_Type_2.Value = 'No Error';
                        plot(app.UIAxes, Y, '*');
                    end
                    
                case app.XYAxisButton
                    
                    str1 = app.Y_Axis_Field.Value;
                    str1 = str1(2: end - 1);
                    str1 = strip(str1);
                    Y = str2double(strsplit(str1));
                    
                    str1 = app.X_Axis_Field.Value;
                    str1 = str1(2: end - 1);
                    str1 = strip(str1);
                    X = str2double(strsplit(str1));
                    
                    if sum(isnan(Y)) ~= 0
                        cla(app.UIAxes);
                        app.Error_Type_2.Value = 'Error Y has non double values';
                    elseif sum(isnan(X)) ~= 0
                        cla(app.UIAxes);
                        app.Error_Type_2.Value = 'Error X has non double values';
                    else
                        plot(app.UIAxes, X, Y, '*');
                    end
                    
                case app.ResultButton
                    
                    str1 = app.Result_2.Value;
                    str1 = str1(2: end - 1);
                    str1 = strip(str1);
                    Res = str2double(strsplit(str1));
                    if sum(isnan(Res)) ~= 0
                        cla(app.UIAxes);
                        app.Error_Type_2.Value = 'Error Res has non double values';
                    else
                        app.Error_Type_2.Value = 'No Error';
                        plot(app.UIAxes, Res, '*');
                    end
                    
                case app.SineButton
                    th = linspace(-3*pi, 3*pi);
                    Sin = sin(th);
                    plot(app.UIAxes, th, Sin);
                    app.Error_Type_2.Value = 'No Error';
                
                case app.CosineButton
                    th = linspace(-3*pi, 3*pi);
                    Cos = cos(th);
                    plot(app.UIAxes, th, Cos);
                    app.Error_Type_2.Value = 'No Error';
                    
               case app.EquationButton
                    if strfind(app.Equation.Value, 'x')
                        str1 = app.Equation.Value;
                        str1 = str1(4: end);
                        str1 = strip(str1);
                        if app.XStart.Value ~= app.XEnd.Value
                            X = linspace(app.XStart.Value, app.XEnd.Value);
                            str1 = str2func(['@(x)', str1]);
                            Fun = eval('str1(X)');
                            plot(app.UIAxes, X, Fun); 
                            app.Error_Type_2.Value = 'No error';
                        else
                            cla(app.UIAxes);
                            app.Error_Type_2.Value = 'Error No range for X found';
                        end
                    else
                        cla(app.UIAxes);
                        app.Error_Type_2.Value = 'Error No parramter X found';
                    end
            end

            
        end

        % Value changed function: HideButton
        function HideButtonValueChanged(app, event)
            
            cla(app.UIAxes); 
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.SimpleCalculatorLabel = uilabel(app.UIFigure);
            app.SimpleCalculatorLabel.Text = 'Simple Calculator';
            app.Error_Con = uieditfield(app.UIFigure, 'text');
            app.Error_Con.Editable = 'off';
            app.Error_Con.Value = 'Console';
            app.Error_Type = uieditfield(app.UIFigure, 'text');
            app.Error_Type.Editable = 'off';
            app.First_Operand = uieditfield(app.UIFigure, 'numeric');
            app.Second_Operand = uieditfield(app.UIFigure, 'numeric');
            app.Operator = uieditfield(app.UIFigure, 'text');
            app.Operator.Editable = 'off';
            app.Equal = uieditfield(app.UIFigure, 'text');
            app.Equal.Editable = 'off';
            app.Equal.Value = '=';
            app.ArithmaticOperationsLabel = uilabel(app.UIFigure);
            app.ArithmaticOperationsLabel.Text = 'Arithmatic Operations';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'+', '-', '*', '/', '%'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '+';
            app.EqualButton = uibutton(app.UIFigure, 'state');
            app.EqualButton.ValueChangedFcn = createCallbackFcn(app, @EqualButtonValueChanged, true);
            app.EqualButton.Text = 'Equal';
            app.YAxisLabel = uilabel(app.UIFigure);
            app.YAxisLabel.Text = 'Y-Axis';
            app.XAxisLabel = uilabel(app.UIFigure);
            app.XAxisLabel.Text = 'X-Axis';
            app.Operator_2 = uieditfield(app.UIFigure, 'text');
            app.Operator_2.Editable = 'off';
            app.Y_Axis_Field = uieditfield(app.UIFigure, 'text');
            app.X_Axis_Field = uieditfield(app.UIFigure, 'text');
            app.ResultLabel = uilabel(app.UIFigure);
            app.ResultLabel.Text = 'Result';
            app.Result_2 = uieditfield(app.UIFigure, 'text');
            app.Result_2.Editable = 'off';
            app.Equal_2 = uieditfield(app.UIFigure, 'text');
            app.Equal_2.Editable = 'off';
            app.Equal_2.Value = '=';
            app.ListBox_2 = uilistbox(app.UIFigure);
            app.ListBox_2.Items = {'+', '-', '*', '/', '%'};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = '+';
            app.EqualButton_2 = uibutton(app.UIFigure, 'state');
            app.EqualButton_2.ValueChangedFcn = createCallbackFcn(app, @EqualButton_2ValueChanged, true);
            app.EqualButton_2.Text = 'Equal';
            app.Error_Con_2 = uieditfield(app.UIFigure, 'text');
            app.Error_Con_2.Editable = 'off';
            app.Error_Con_2.Value = 'Console';
            app.Error_Type_2 = uieditfield(app.UIFigure, 'text');
            app.Error_Type_2.Editable = 'off';
            app.EnterequationyxLabel = uilabel(app.UIFigure);
            app.EnterequationyxLabel.Text = 'Enter equation y(x)';
            app.Equation = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            app.PlottingButtonsGroupButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottingButtonsGroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlottingButtonsGroupButtonGroupSelectionChanged, true);
            app.PlottingButtonsGroupButtonGroup.Title = 'Plotting Buttons Group';
            app.XAxisButton = uiradiobutton(app.PlottingButtonsGroupButtonGroup);
            app.XAxisButton.Text = 'X-Axis';
            app.XAxisButton.Value = true;
            app.YAxisButton = uiradiobutton(app.PlottingButtonsGroupButtonGroup);
            app.YAxisButton.Text = 'Y-Axis';
            app.XYAxisButton = uiradiobutton(app.PlottingButtonsGroupButtonGroup);
            app.XYAxisButton.Text = 'X Y Axis';
            app.ResultButton = uiradiobutton(app.PlottingButtonsGroupButtonGroup);
            app.ResultButton.Text = 'Result';
            app.SineButton = uiradiobutton(app.PlottingButtonsGroupButtonGroup);
            app.SineButton.Text = 'Sine';
            app.CosineButton = uiradiobutton(app.PlottingButtonsGroupButtonGroup);
            app.CosineButton.Text = 'Cosine';
            app.EquationButton = uiradiobutton(app.PlottingButtonsGroupButtonGroup);
            app.EquationButton.Text = 'Equation';
            app.XLabel = uilabel(app.UIFigure);
            app.XLabel.Text = 'X:';
            app.XStart = uieditfield(app.UIFigure, 'numeric');
            app.TOLabel = uilabel(app.UIFigure);
            app.TOLabel.Text = 'TO';
            app.XEnd = uieditfield(app.UIFigure, 'numeric');
            app.ShowButton = uibutton(app.UIFigure, 'state');
            app.ShowButton.ValueChangedFcn = createCallbackFcn(app, @ShowButtonValueChanged, true);
            app.ShowButton.Text = 'Show';
            app.HideButton = uibutton(app.UIFigure, 'state');
            app.HideButton.ValueChangedFcn = createCallbackFcn(app, @HideButtonValueChanged, true);
            app.HideButton.Text = 'Hide';
            app.Result = uieditfield(app.UIFigure, 'text');
            app.Result.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Lab0202

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end